classdef INTERFAZ_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IniciartareasButton           matlab.ui.control.Button
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        XEditField_2Label             matlab.ui.control.Label
        XEditField_2                  matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        YEditField_2                  matlab.ui.control.NumericEditField
        XEditField_3Label             matlab.ui.control.Label
        XEditField_3                  matlab.ui.control.NumericEditField
        YEditField_3Label             matlab.ui.control.Label
        YEditField_3                  matlab.ui.control.NumericEditField
        PhiEditFieldLabel             matlab.ui.control.Label
        PhiEditField                  matlab.ui.control.NumericEditField
        PhiEditField_2Label           matlab.ui.control.Label
        PhiEditField_2                matlab.ui.control.NumericEditField
        PhiEditField_3Label           matlab.ui.control.Label
        PhiEditField_3                matlab.ui.control.NumericEditField
        XEditField_4Label             matlab.ui.control.Label
        XEditField_4                  matlab.ui.control.NumericEditField
        YEditField_4Label             matlab.ui.control.Label
        YEditField_4                  matlab.ui.control.NumericEditField
        PhiEditField_4Label           matlab.ui.control.Label
        PhiEditField_4                matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        XEditField_6Label             matlab.ui.control.Label
        XEditField_6                  matlab.ui.control.NumericEditField
        YEditField_6Label             matlab.ui.control.Label
        YEditField_6                  matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        XEditField_7Label             matlab.ui.control.Label
        XEditField_7                  matlab.ui.control.NumericEditField
        YEditField_7Label             matlab.ui.control.Label
        YEditField_7                  matlab.ui.control.NumericEditField
        ZEditField_2Label             matlab.ui.control.Label
        ZEditField_2                  matlab.ui.control.NumericEditField
        XEditField_8Label             matlab.ui.control.Label
        XEditField_8                  matlab.ui.control.NumericEditField
        YEditField_8Label             matlab.ui.control.Label
        YEditField_8                  matlab.ui.control.NumericEditField
        ZEditField_3Label             matlab.ui.control.Label
        ZEditField_3                  matlab.ui.control.NumericEditField
        XEditField_9Label             matlab.ui.control.Label
        XEditField_9                  matlab.ui.control.NumericEditField
        YEditField_9Label             matlab.ui.control.Label
        YEditField_9                  matlab.ui.control.NumericEditField
        ZEditField_4Label             matlab.ui.control.Label
        ZEditField_4                  matlab.ui.control.NumericEditField
        C1Lamp                        matlab.ui.control.Lamp
        C2Lamp                        matlab.ui.control.Lamp
        C3Lamp                        matlab.ui.control.Lamp
        C4Lamp                        matlab.ui.control.Lamp
        C1Lamp_2                      matlab.ui.control.Lamp
        C2Lamp_2                      matlab.ui.control.Lamp
        C3Lamp_2                      matlab.ui.control.Lamp
        C4Lamp_2                      matlab.ui.control.Lamp
        NdetareasSpinnerLabel         matlab.ui.control.Label
        NdetareasSpinner              matlab.ui.control.Spinner
        CoordenadasdelrobotmvilLabel  matlab.ui.control.Label
        CoordenadasefectorfinalLabel  matlab.ui.control.Label
        ABCLabel                      matlab.ui.control.Label
        ABCLabel_2                    matlab.ui.control.Label
        ABCLabel_3                    matlab.ui.control.Label
        ABCLabel_4                    matlab.ui.control.Label
        ABCLabel_5                    matlab.ui.control.Label
        ABCLabel_6                    matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        PhiLabel                      matlab.ui.control.Label
        XLabel_2                      matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Inizializar parametros generales de la aplicación
        %Coordenadas robot movil
        x0 = 0; 
        y0 = 0;
        p0 = 0;
        % Configurar coordenadas iniciales
        xr = 0; 
        yr = 0;
        r1 = 0; r2 = 0; r3 = 0; r4 =0;
        phi =0;
        % Matriz de posiciones para secuencia del DDR
        P = zeros(4,3);
        % Matriz de posiciones para secuencia del SCARA
        PS = zeros(4,3);
        N = 1; % Contador de secuencias
        c = 0;
        f = 0;
        %% coordenadas efector final
        xs = 40;
        ys = 0;
        zs = 0;
        th_init = [0; 0; 0];

    end
    methods (Access = private)
                          
      %Función para generar la secuancia automatica
        function secuencia(app)
            if (app.N <= 0) % Restriccion de coordenadas
            app.Label.FontColor = 'r';
            app.Label.Text = 'Numero de coordenadas invalido.';
            pause(3)
            app.Label.FontColor = 'k';
            app.Label.Text = 'Introducir coordenadas.';
            else 
            % Calculo de dimensiones maximas para el brazo 
            app.r1 = sqrt(app.PS(1,1)^2 + app.PS(1,2)^2);
            app.r2 = sqrt(app.PS(2,1)^2 + app.PS(2,2)^2);
            app.r3 = sqrt(app.PS(3,1)^2 + app.PS(3,2)^2);
            app.r4 = sqrt(app.PS(4,1)^2 + app.PS(4,2)^2);
            
            if (app.r1 > 20 || app.r2 > 20 || app.r3 > 20 || app.r4 > 20 || ...
                    (app.PS(1,1) == 0 && app.PS(1,2) == 0 && app.N == 1) || ...
                    (app.PS(2,1) == 0 && app.PS(2,2) == 0 && app.N >= 1) || ...
                    (app.PS(3,1) == 0 && app.PS(3,2) == 0 && app.N >= 1) || ...
                    (app.PS(4,1) == 0 && app.PS(4,2) == 0 && app.N >= 1) || ...
                    (app.PS(1,3) < -10 && app.N == 1) || ...
                    (app.PS(2,3) < -10 && app.N >= 1) || ...
                    (app.PS(3,3) < -10 && app.N >= 1) || ...
                    (app.PS(4,3) < -10 && app.N >= 1))
            app.Label.FontColor = 'r';
            app.Label.Text = 'Las coordendas del brazo estan fuera de sus dimensiones.';
            pause(4)
            app.Label.FontColor = 'k';
            app.Label.Text = 'Introducir coordenadas.';
            else 
            app.Label.FontColor = 'b';
            app.Label.Text = 'Secuencia en proceso ...';
            
            %Función para realizar la secuencia de las coordenadas objetivo
            roboto = control_DDR(app.xr, app.yr, app.phi);

            control_th1 = control_SCARA(0.000022);
            control_th2 = control_SCARA(0.0000046);
            control_th3 = control_SCARA(0.000011);

            control_th = [control_th1 control_th2 control_th3];
            % Longitudes en centimetros
                l1 = 10;   %---> h desfase de altura con respecto al suelo
                l2 = 10;   %---> L1 longitud del primer eslabón
                l3 = 10;   %---> L2 longitud del segundo eslabón
                d3_max = 10; %---> longitud del tercer eslabón
                d = (80/4); %---> metros que se mueve por cada 360°
            
           for i = 1: app.N
                        
            % Posiciones objetivo
            app.x0 = app.P(i,1);
            app.y0 = app.P(i,2);
            th0 = app.P(i,3);
            px = app.PS(i,1);
            py = app.PS(i,2);
            pz = app.PS(i,3) + 10;
            % Posiciones actuales robot movil
            app.ABCLabel.Text = num2str(app.xr,4);
            app.ABCLabel_2.Text = num2str(app.yr,4);
            app.ABCLabel_3.Text = num2str(rad2deg(app.phi),4);
            
            th_final = IK_SCARA(px, py, pz, l1, l2, l3, app.th_init, d);
            
            T00 = [cos(app.phi) -sin(app.phi) 0 app.xr; sin(app.phi) cos(app.phi) 0 app.yr; 0 0 1 0; 0 0 0 1];
            [T02, T03] = DK_SCARA(T00, app.th_init, l1, l2, l3, d);
            plot3(app.UIAxes,app.xr, app.yr, 0,'.')
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+1.57*pi)), app.yr+(10*sin(app.phi+1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi-1.57*pi)), app.yr+(10*sin(app.phi-1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+pi)), app.yr+(10*sin(app.phi+pi)), 0, 'o','LineWidth',2,'MarkerSize',4,'MarkerEdgeColor','b')
            plot3(app.UIAxes,[app.xr T02(1,4)],[app.yr T02(2,4)],[l1 T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes,[T02(1,4) T03(1,4)],[T02(2,4) T03(2,4)],[T02(3,4) T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes,[T03(1,4) T03(1,4)],[T03(2,4) T03(2,4)],[T03(3,4) T03(3,4)+d3_max])
            plot3(app.UIAxes,  app.xr , app.yr, l1,'o')
            plot3(app.UIAxes,  T02(1,4) , T02(2,4), T02(3,4),'o')
            plot3(app.UIAxes,  T03(1,4) , T03(2,4), T03(3,4),'v')
            hold(app.UIAxes, 'off') 
            pause(0.01)
            
            while (abs(app.xr-app.x0) > 0.01 || abs(app.yr-app.y0) > 0.01 || abs(app.phi-th0) > 0.03)
            % Posiciones actuales robot movil
            app.ABCLabel.Text = num2str(app.xr,4);
            app.ABCLabel_2.Text = num2str(app.yr,4);
            app.ABCLabel_3.Text = num2str(rad2deg(app.phi),4);
            
            %[xr, yr, phi] = roboto.control(-1, 15, pi*1/2);
            [app.xr, app.yr, app.phi] = roboto.control(app.x0, app.y0, th0);
            T00 = [cos(app.phi) -sin(app.phi) 0 app.xr; sin(app.phi) cos(app.phi) 0 app.yr; 0 0 1 0; 0 0 0 1];
            [T02, T03] = DK_SCARA(T00, app.th_init, l1, l2, l3, d);
            plot3(app.UIAxes, app.xr, app.yr, 0,'.')
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+1.57*pi)), app.yr+(10*sin(app.phi+1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi-1.57*pi)), app.yr+(10*sin(app.phi-1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+pi)), app.yr+(10*sin(app.phi+pi)), 0, 'o','LineWidth',2,'MarkerSize',4,'MarkerEdgeColor','b')
            plot3(app.UIAxes, [app.xr app.xr],[app.yr app.yr],[0 l1])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [app.xr T02(1,4)],[app.yr T02(2,4)],[l1 T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T02(1,4) T03(1,4)],[T02(2,4) T03(2,4)],[T02(3,4) T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T03(1,4) T03(1,4)],[T03(2,4) T03(2,4)],[T03(3,4) T03(3,4)+d3_max])
            plot3(app.UIAxes,  app.xr , app.yr, l1,'o')
            plot3(app.UIAxes,  T02(1,4) , T02(2,4), T02(3,4),'o')
            plot3(app.UIAxes,  T03(1,4) , T03(2,4), T03(3,4),'v')
            hold(app.UIAxes, 'off')
            pause(0.01)
            end
            
            app.c = app.c + 1; % contador de secuancias DDR
        if app.c >= 1          app.C1Lamp.Color = 'g';        end
        if app.c >= 2          app.C2Lamp.Color = 'g';        end
        if app.c >= 3          app.C3Lamp.Color = 'g';        end
        if app.c >= 4          app.C4Lamp.Color = 'g';        end
            
        T00 = [cos(app.phi) -sin(app.phi) 0 app.xr; sin(app.phi) cos(app.phi) 0 app.yr; 0 0 1 0; 0 0 0 1];
    
    for i = 1:3
        ik_1 = 0;
        wk_1 = 0;
        Vk_1 = 0;
        e2k_1 = 0;
        while (abs(app.th_init(i)-th_final(i)) > 0.01)
            [app.th_init(i),ik_1,wk_1,Vk_1,e2k_1] = control_th(i).control(app.th_init(i),th_final(i),ik_1,wk_1,Vk_1,e2k_1);
            [T02, T03] = DK_SCARA(T00, app.th_init, l1, l2, l3, d);
            plot3(app.UIAxes, app.xr, app.yr, 0,'.')
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+1.57*pi)), app.yr+(10*sin(app.phi+1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi-1.57*pi)), app.yr+(10*sin(app.phi-1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+pi)), app.yr+(10*sin(app.phi+pi)), 0, 'o','LineWidth',2,'MarkerSize',4,'MarkerEdgeColor','b')
            plot3(app.UIAxes, [app.xr app.xr],[app.yr app.yr],[0 l1])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [app.xr T02(1,4)],[app.yr T02(2,4)],[l1 T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T02(1,4) T03(1,4)],[T02(2,4) T03(2,4)],[T02(3,4) T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T03(1,4) T03(1,4)],[T03(2,4) T03(2,4)],[T03(3,4) T03(3,4)+d3_max])
            plot3(app.UIAxes,  app.xr , app.yr, l1,'o')
            plot3(app.UIAxes,  T02(1,4) , T02(2,4), T02(3,4),'o')
            plot3(app.UIAxes,  T03(1,4) , T03(2,4), T03(3,4),'v')
                        %Posciones actuales efector final
            T03_S = inv(T00)*T03;
            app.xs = T03_S(1,4);
            app.ys = T03_S(2,4);
            app.zs = T03_S(3,4);
            app.ABCLabel_4.Text = num2str(app.xs,4);
            app.ABCLabel_5.Text = num2str(app.ys,4);
            app.ABCLabel_6.Text = num2str(app.zs-10,4);
            hold(app.UIAxes, 'off')
            pause(0.01)
        end
    end
    
        app.f = app.f + 1; % contador de secuencias SCARA
        if app.f >= 1          app.C1Lamp_2.Color = 'g';        end
        if app.f >= 2          app.C2Lamp_2.Color = 'g';        end
        if app.f >= 3          app.C3Lamp_2.Color = 'g';        end
        if app.f >= 4          app.C4Lamp_2.Color = 'g';        end
        
           end
           
           app.Label.FontColor = 'b';
           app.Label.Text = 'Fin de la secuencia.';
           pause(4)
           app.Label.FontColor = 'k';
           app.Label.Text = 'Introducir coordenadas.';
            end
            end
        end
        
        function inicio(app)
            app.Label.Text = 'Introducir coordenadas.';
            app.ABCLabel.Text = num2str(app.xr,4);
            app.ABCLabel_2.Text = num2str(app.yr,4);
            app.ABCLabel_3.Text = num2str(rad2deg(app.phi),4);
            % Longitudes en centimetros
                l1 = 10;   %---> h desfase de altura con respecto al suelo
                l2 = 10;   %---> L1 longitud del primer eslabón
                l3 = 10;   %---> L2 longitud del segundo eslabón
                d3_max = 10; %---> longitud del tercer eslabón
                d = (80/4)/100; %---> metros que se mueve por cada 360°
                
            T00 = [cos(app.phi) -sin(app.phi) 0 app.xr; sin(app.phi) cos(app.phi) 0 app.yr; 0 0 1 0; 0 0 0 1];
            [T02, T03] = DK_SCARA(T00, app.th_init, l1, l2, l3, d);
            plot3(app.UIAxes, app.xr, app.yr, 0,'.')
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on');
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+1.57*pi)), app.yr+(10*sin(app.phi+1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi-1.57*pi)), app.yr+(10*sin(app.phi-1.57*pi)), 0, 'o','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b') % llanta 1
    plot3(app.UIAxes, app.xr+(10*cos(app.phi+pi)), app.yr+(10*sin(app.phi+pi)), 0, 'o','LineWidth',2,'MarkerSize',4,'MarkerEdgeColor','b')
            plot3(app.UIAxes, [app.xr app.xr],[app.yr app.yr],[0 l1])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [app.xr T02(1,4)],[app.yr T02(2,4)],[l1 T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T02(1,4) T03(1,4)],[T02(2,4) T03(2,4)],[T02(3,4) T02(3,4)])
            hold(app.UIAxes, 'on');            grid(app.UIAxes, 'on')
            plot3(app.UIAxes, [T03(1,4) T03(1,4)],[T03(2,4) T03(2,4)],[T03(3,4) T03(3,4)+d3_max])
            plot3(app.UIAxes,  app.xr , app.yr, l1,'o')
            plot3(app.UIAxes,  T02(1,4) , T02(2,4), T02(3,4),'o')
            plot3(app.UIAxes,  T03(1,4) , T03(2,4), T03(3,4),'v')
                        hold(app.UIAxes, 'off')
            %Posciones actuales efector final
            T03_S = inv(T00)*T03;
            app.xs = T03_S(1,4);
            app.ys = T03_S(2,4);
            app.zs = T03_S(3,4);
            app.ABCLabel_4.Text = num2str(app.xs,4);
            app.ABCLabel_5.Text = num2str(app.ys,4);
            app.ABCLabel_6.Text = num2str(app.zs-10,4);
          
                        % Dimensiones en cm
                        app.UIAxes.XLim = [-100 100];
                        app.UIAxes.YLim = [-100 100];
                        app.UIAxes.ZLim = [0 20];
        end
        function resetlamp(app)
                app.c = 0; app.f = 0;
                app.C1Lamp.Color = 'w';
                app.C2Lamp.Color = 'w';
                app.C3Lamp.Color = 'w';
                app.C4Lamp.Color = 'w';
                app.C1Lamp_2.Color = 'w';
                app.C2Lamp_2.Color = 'w';
                app.C3Lamp_2.Color = 'w';
                app.C4Lamp_2.Color = 'w';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetlamp(app);
            app.N = 0;
            inicio(app)
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.P(1,2) = value;
        end

        % Button pushed function: IniciartareasButton
        function IniciartareasButtonPushed(app, event)
            resetlamp(app);
            secuencia(app);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.P(1,1) = value;
        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            value = app.PhiEditField.Value;
            app.P(1,3) = deg2rad(value);
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            value = app.XEditField_2.Value;
            app.P(2,1) = value;           
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            value = app.YEditField_2.Value;
            app.P(2,2) = value;
        end

        % Value changed function: XEditField_3
        function XEditField_3ValueChanged(app, event)
            value = app.XEditField_3.Value;
            app.P(3,1) = value;
        end

        % Value changed function: YEditField_3
        function YEditField_3ValueChanged(app, event)
            value = app.YEditField_3.Value;
            app.P(3,2) = value;
        end

        % Value changed function: XEditField_4
        function XEditField_4ValueChanged(app, event)
            value = app.XEditField_4.Value;
            app.P(4,1) = value;
        end

        % Value changed function: YEditField_4
        function YEditField_4ValueChanged(app, event)
            value = app.YEditField_4.Value;
            app.P(4,2) = value;
        end

        % Value changed function: PhiEditField_2
        function PhiEditField_2ValueChanged(app, event)
            value = app.PhiEditField_2.Value;
            app.P(2,3) = deg2rad(value);
        end

        % Value changed function: PhiEditField_3
        function PhiEditField_3ValueChanged(app, event)
            value = app.PhiEditField_3.Value;
            app.P(3,3) = deg2rad(value);
        end

        % Value changed function: PhiEditField_4
        function PhiEditField_4ValueChanged(app, event)
            value = app.PhiEditField_4.Value;
            app.P(3,3) = deg2rad(value);
        end

        % Callback function
        function XEditField_5ValueChanged(app, event)
            value = app.XEditField_5.Value;
            app.xr = value;
        end

        % Callback function
        function YEditField_5ValueChanged(app, event)
            value = app.YEditField_5.Value;
            app.yr = value;
        end

        % Callback function
        function OrigenButtonPushed(app, event)
            origen(app);
        end

        % Callback function
        function PhiEditField_5ValueChanged(app, event)
            value = app.PhiEditField_5.Value;
            app.phi = deg2rad(value);
        end

        % Callback function
        function ResetButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
            app.P = zeros(4,3);
            app.PS = zeros(4,3);
            inicio(app)
            grid(app.UIAxes, 'on')
        end

        % Value changed function: XEditField_6
        function XEditField_6ValueChanged(app, event)
            value = app.XEditField_6.Value;
            app.PS(1,1) = value;
        end

        % Value changed function: YEditField_6
        function YEditField_6ValueChanged(app, event)
            value = app.YEditField_6.Value;
            app.PS(1,2) = value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.PS(1,3) = value;
        end

        % Value changed function: XEditField_7
        function XEditField_7ValueChanged(app, event)
            value = app.XEditField_7.Value;
                        app.PS(2,1) = value;
        end

        % Value changed function: XEditField_8
        function XEditField_8ValueChanged(app, event)
            value = app.XEditField_8.Value;
                        app.PS(3,1) = value;
        end

        % Value changed function: XEditField_9
        function XEditField_9ValueChanged(app, event)
            value = app.XEditField_9.Value;
                        app.PS(4,1) = value;
        end

        % Value changed function: YEditField_7
        function YEditField_7ValueChanged(app, event)
            value = app.YEditField_7.Value;
                        app.PS(2,2) = value;
        end

        % Value changed function: YEditField_8
        function YEditField_8ValueChanged(app, event)
            value = app.YEditField_8.Value;
            app.PS(3,2) = value;
        end

        % Value changed function: YEditField_9
        function YEditField_9ValueChanged(app, event)
            value = app.YEditField_9.Value;
            app.PS(4,2) = value;
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            value = app.ZEditField_2.Value;
            app.PS(2,3) = value;
        end

        % Value changed function: ZEditField_3
        function ZEditField_3ValueChanged(app, event)
            value = app.ZEditField_3.Value;
            app.PS(3,3) = value;
        end

        % Value changed function: ZEditField_4
        function ZEditField_4ValueChanged(app, event)
            value = app.ZEditField_4.Value;
            app.PS(4,3) = value;
        end

        % Callback function
        function NEditFieldValueChanged(app, event)
            
        end

        % Value changed function: NdetareasSpinner
        function NdetareasSpinnerValueChanged(app, event)
            value = app.NdetareasSpinner.Value;
            app.N = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IniciartareasButton = uibutton(app.UIFigure, 'push');
            app.IniciartareasButton.ButtonPushedFcn = createCallbackFcn(app, @IniciartareasButtonPushed, true);
            app.IniciartareasButton.Text = 'Iniciar tareas';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '-')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.XEditField_3Label = uilabel(app.UIFigure);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_3.ValueChangedFcn = createCallbackFcn(app, @XEditField_3ValueChanged, true);
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3.ValueChangedFcn = createCallbackFcn(app, @YEditField_3ValueChanged, true);
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.PhiEditField_2Label = uilabel(app.UIFigure);
            app.PhiEditField_2Label.Text = 'Phi';
            app.PhiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhiEditField_2ValueChanged, true);
            app.PhiEditField_3Label = uilabel(app.UIFigure);
            app.PhiEditField_3Label.Text = 'Phi';
            app.PhiEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField_3.ValueChangedFcn = createCallbackFcn(app, @PhiEditField_3ValueChanged, true);
            app.XEditField_4Label = uilabel(app.UIFigure);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_4.ValueChangedFcn = createCallbackFcn(app, @XEditField_4ValueChanged, true);
            app.YEditField_4Label = uilabel(app.UIFigure);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4.ValueChangedFcn = createCallbackFcn(app, @YEditField_4ValueChanged, true);
            app.PhiEditField_4Label = uilabel(app.UIFigure);
            app.PhiEditField_4Label.Text = 'Phi';
            app.PhiEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditField_4.ValueChangedFcn = createCallbackFcn(app, @PhiEditField_4ValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'CIO.jpg';
            app.Label = uilabel(app.UIFigure);
            app.XEditField_6Label = uilabel(app.UIFigure);
            app.XEditField_6Label.Text = 'X';
            app.XEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_6.ValueChangedFcn = createCallbackFcn(app, @XEditField_6ValueChanged, true);
            app.YEditField_6Label = uilabel(app.UIFigure);
            app.YEditField_6Label.Text = 'Y';
            app.YEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_6.ValueChangedFcn = createCallbackFcn(app, @YEditField_6ValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.XEditField_7Label = uilabel(app.UIFigure);
            app.XEditField_7Label.Text = 'X';
            app.XEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_7.ValueChangedFcn = createCallbackFcn(app, @XEditField_7ValueChanged, true);
            app.YEditField_7Label = uilabel(app.UIFigure);
            app.YEditField_7Label.Text = 'Y';
            app.YEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_7.ValueChangedFcn = createCallbackFcn(app, @YEditField_7ValueChanged, true);
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.XEditField_8Label = uilabel(app.UIFigure);
            app.XEditField_8Label.Text = 'X';
            app.XEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_8.ValueChangedFcn = createCallbackFcn(app, @XEditField_8ValueChanged, true);
            app.YEditField_8Label = uilabel(app.UIFigure);
            app.YEditField_8Label.Text = 'Y';
            app.YEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_8.ValueChangedFcn = createCallbackFcn(app, @YEditField_8ValueChanged, true);
            app.ZEditField_3Label = uilabel(app.UIFigure);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_3.ValueChangedFcn = createCallbackFcn(app, @ZEditField_3ValueChanged, true);
            app.XEditField_9Label = uilabel(app.UIFigure);
            app.XEditField_9Label.Text = 'X';
            app.XEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_9.ValueChangedFcn = createCallbackFcn(app, @XEditField_9ValueChanged, true);
            app.YEditField_9Label = uilabel(app.UIFigure);
            app.YEditField_9Label.Text = 'Y';
            app.YEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_9.ValueChangedFcn = createCallbackFcn(app, @YEditField_9ValueChanged, true);
            app.ZEditField_4Label = uilabel(app.UIFigure);
            app.ZEditField_4Label.Text = 'Z';
            app.ZEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_4.ValueChangedFcn = createCallbackFcn(app, @ZEditField_4ValueChanged, true);
            app.C1Lamp = uilamp(app.UIFigure);
            app.C2Lamp = uilamp(app.UIFigure);
            app.C3Lamp = uilamp(app.UIFigure);
            app.C4Lamp = uilamp(app.UIFigure);
            app.C1Lamp_2 = uilamp(app.UIFigure);
            app.C2Lamp_2 = uilamp(app.UIFigure);
            app.C3Lamp_2 = uilamp(app.UIFigure);
            app.C4Lamp_2 = uilamp(app.UIFigure);
            app.NdetareasSpinnerLabel = uilabel(app.UIFigure);
            app.NdetareasSpinnerLabel.Text = 'N. de tareas';
            app.NdetareasSpinner = uispinner(app.UIFigure);
            app.NdetareasSpinner.ValueChangedFcn = createCallbackFcn(app, @NdetareasSpinnerValueChanged, true);
            app.CoordenadasdelrobotmvilLabel = uilabel(app.UIFigure);
            app.CoordenadasdelrobotmvilLabel.Text = 'Coordenadas del robot móvil';
            app.CoordenadasefectorfinalLabel = uilabel(app.UIFigure);
            app.CoordenadasefectorfinalLabel.Text = 'Coordenadas efector final';
            app.ABCLabel = uilabel(app.UIFigure);
            app.ABCLabel.Text = 'ABC';
            app.ABCLabel_2 = uilabel(app.UIFigure);
            app.ABCLabel_2.Text = 'ABC';
            app.ABCLabel_3 = uilabel(app.UIFigure);
            app.ABCLabel_3.Text = 'ABC';
            app.ABCLabel_4 = uilabel(app.UIFigure);
            app.ABCLabel_4.Text = 'ABC';
            app.ABCLabel_5 = uilabel(app.UIFigure);
            app.ABCLabel_5.Text = 'ABC';
            app.ABCLabel_6 = uilabel(app.UIFigure);
            app.ABCLabel_6.Text = 'ABC';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.PhiLabel = uilabel(app.UIFigure);
            app.PhiLabel.Text = 'Phi:';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X:';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y:';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZ_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end